classdef fem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        FEMPROJECTLabel                 matlab.ui.control.Label
        ConsistentNodalAnalysisLabel    matlab.ui.control.Label
        EnterButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        OutputLabel                     matlab.ui.control.Label
        OutputLabel_2                   matlab.ui.control.Label
        NoofNodesEditFieldLabel         matlab.ui.control.Label
        NoofNodesEditField              matlab.ui.control.NumericEditField
        XcoordinatesofnodesEditFieldLabel  matlab.ui.control.Label
        XcoordinatesofnodesEditField    matlab.ui.control.EditField
        TypeofLoadingDropDownLabel      matlab.ui.control.Label
        TypeofLoadingDropDown           matlab.ui.control.DropDown
        ConsistentNodalForcesEditFieldLabel  matlab.ui.control.Label
        ConsistentNodalForcesEditField  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
           value = app.TypeofLoadingDropDown.Value;
           sym x;
        
           t  = str2num(app.XcoordinatesofnodesEditField.Value);
        
           switch value
               
               case {'Uniform', 'Linear', 'Quadratic', 'Sinusoidal'}  % User selected a defined option
                  p = inputdlg({'Enter the load function'});
                  p = str2sym(p);
                               
                  syms f g
                  n = length(t);                % No. of nodes
                  L = t(n) - t(1);              % Length of element
                   
                  %Defining Shape Functions :
                  syms N x

                  N(1) = piecewise(t(1)< x< t(2), (x - t(2))/(t(1) - t(2)), x>t(2), 0);
                  N(n) = piecewise(x<t(n-1), 0, t(n-1)< x< t(n), (x - t(n-1))/(t(n) - t(n-1)));
    
                  for i = 2:n-1
                      N(i) = piecewise(x<t(i-1), 0, t(i-1)< x< t(i), (x - t(i-1))/(t(i) - t(i-1)), t(i)< x< t(i+1), (x - t(i+1))/(t(i) - t(i+1)), x>t(i+1), 0);
                  end
                  
                  %Consistent Nodal Forces :
                  for i = 1:n
                      g = N(i)*p;
                      f(i) = int(g, x, t(1), t(n));
                  end
                 
                  sy = f;                 %insure input is symbolic
                  siz = prod(size(sy));   %find the number of elements in "sy"
                  
                  for i = 1:2:2*siz-1                 %dump it into a cell array with the same number of elements
                        in{i} = char(sy((i+1)/2));    %convert to char
                        in{i+1} = ' ';
                  end
                  
                  if siz == 1
                        in = char(in);     %revert back to a 'char' array for single answers
                  end
                  out = in
                  
                  app.ConsistentNodalForcesEditField.Value = cell2mat(out);
                   
                  for i=1:n-1
                      plot(app.UIAxes,t(i),0,'->');
                      plot(app.UIAxes,[t(i) t(i+1)],[0 0],'-r');    
                      hold(app.UIAxes,"on") 
                  end
                  
                  plot(app.UIAxes,t(1),0,'->');
                  plot(app.UIAxes,t(n),0,'->');
               
               case {'Generic'}  % User typed a value
                  answer = inputdlg({'Enter no. of discontinuities in load function','Enter the X co-ordinates of discontinuities','Enter piece wise load functions'});
                  y = str2num(answer{2});
                  a = answer{3};
 
                  p  = [];
                  
                  b  = erase(a,['a(0)','a(length(a))']);
                  a  = split(b,[" ",","]);
                  
                  for i = 1:length(a)
                      p = [p,str2sym(a(i))];
                  end
                  
                  %t - x co-ordinates array of nodes
                  %y - x co-ordinates array of discontinuities
                  %p - Load functions array
                  
                  n = length(t);                % No. of nodes
                  L = t(n) - t(1);              % Length of element
                  m = length(p);                % No. of piece-wise functions
                  
                  %Defining Shape Functions :
                  syms N x

                  N(1) = piecewise(t(1)< x< t(2), (x - t(2))/(t(1) - t(2)), x>t(2), 0);
                  N(n) = piecewise(x<t(n-1), 0, t(n-1)< x< t(n), (x - t(n-1))/(t(n) - t(n-1)));
    
                  for i = 2:n-1
                      N(i) = piecewise(x<t(i-1), 0, t(i-1)< x< t(i), (x - t(i-1))/(t(i) - t(i-1)), t(i)< x< t(i+1), (x - t(i+1))/(t(i) - t(i+1)), x>t(i+1), 0);
                  end
                  
                  %Re-writing Load Function :
                  syms P

                  if length(y) ~=0
                      p(1) = piecewise(x<t(1), 0, t(1)< x< y(1), p(1), x>y(1), 0);
                      p(m) = piecewise(x<y(m-1), 0, y(m-1)< x< t(n), p(m), x>t(n), 0);

                      for i = 2:m-1
                          p(i) = piecewise(x<y(i-1), 0, y(i-1)< x< y(i), p(i), x>y(i), 0);
                      end

                      P = 0;

                      for i = 1:m
                          P = P + p(i);
                      end
   
                  else 
                      P = p(1);
                  end
                
                  %Consistent Nodal Forces :
                  syms f g

                  for i = 1:n
                      g = N(i)*P;
                      f(i) = int(g, x, t(1), t(n) );
                  end
               
                  sy = f;                 %ensure input is symbolic
                  siz = prod(size(sy));   %find the number of elements in "sy"
                  
                  for i = 1:siz                 %dump it into a cell array with the same number of elements
                        in{i} = char(sy(i));    %convert to char
                  end
                  
                  if siz == 1
                        in = char(in);     %revert back to a 'char' array for single answers
                  end
                  out = in
                
                  app.ConsistentNodalForcesEditField.Value = cell2mat(out);
                   
                  for i=1:n-1
                      plot(app.UIAxes,t(i),0,'->');
                      plot(app.UIAxes,[t(i) t(i+1)],[0 0],'-r');    
                      hold(app.UIAxes,"on") 
                  end
                  
                  plot(app.UIAxes,t(1),0,'->');
                  plot(app.UIAxes,t(n),0,'->');

           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.FEMPROJECTLabel = uilabel(app.Panel);
            app.FEMPROJECTLabel.Text = 'FEM PROJECT';
            app.ConsistentNodalAnalysisLabel = uilabel(app.Panel);
            app.ConsistentNodalAnalysisLabel.Text = 'Consistent Nodal Analysis';
            app.EnterButton = uibutton(app.Panel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Nodal Positions')
            ylabel(app.UIAxes, 'Consistent Nodal Forces')
            app.OutputLabel = uilabel(app.Panel);
            app.OutputLabel.Text = 'Output';
            app.OutputLabel_2 = uilabel(app.Panel);
            app.OutputLabel_2.Text = 'Output';
            app.NoofNodesEditFieldLabel = uilabel(app.Panel);
            app.NoofNodesEditFieldLabel.Text = 'No. of Nodes';
            app.NoofNodesEditField = uieditfield(app.Panel, 'numeric');
            app.NoofNodesEditField.Value = 4;
            app.XcoordinatesofnodesEditFieldLabel = uilabel(app.Panel);
            app.XcoordinatesofnodesEditFieldLabel.Text = 'X co-ordinates of nodes';
            app.XcoordinatesofnodesEditField = uieditfield(app.Panel, 'text');
            app.XcoordinatesofnodesEditField.Value = ' 0 2 5 9 ';
            app.TypeofLoadingDropDownLabel = uilabel(app.Panel);
            app.TypeofLoadingDropDownLabel.Text = 'Type of Loading';
            app.TypeofLoadingDropDown = uidropdown(app.Panel);
            app.TypeofLoadingDropDown.Items = {'Uniform', 'Linear', 'Quadratic', 'Sinusoidal', 'Generic'};
            app.TypeofLoadingDropDown.Value = 'Uniform';
            app.ConsistentNodalForcesEditFieldLabel = uilabel(app.Panel);
            app.ConsistentNodalForcesEditFieldLabel.Text = 'Consistent Nodal Forces';
            app.ConsistentNodalForcesEditField = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end